classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        B_Dot       matlab.ui.control.Button
        B0          matlab.ui.control.Button
        B_Equal     matlab.ui.control.Button
        B3          matlab.ui.control.Button
        B2          matlab.ui.control.Button
        B1          matlab.ui.control.Button
        B_Multiply  matlab.ui.control.Button
        B6          matlab.ui.control.Button
        B5          matlab.ui.control.Button
        B4          matlab.ui.control.Button
        B_Divide    matlab.ui.control.Button
        B9          matlab.ui.control.Button
        B8          matlab.ui.control.Button
        B7          matlab.ui.control.Button
        B_Plus      matlab.ui.control.Button
        B_Minus     matlab.ui.control.Button
        B_Clear     matlab.ui.control.Button
        B_Back      matlab.ui.control.Button
        Text        matlab.ui.control.EditField
        Gauge       matlab.ui.control.LinearGauge
    end

    
    properties (Access = private)
        Fs = 8000; % Description
        nBits = 16; % Description
        nChannels = 1; % Description
        recDur = 2; % Description
        wavName = 'temp.wav';
    end
    
    methods (Access = private)
        
        function gaugeMover(app, value)
            app.Gauge.Value = value;
        end
        
        function audioProcessor(app)
            disp('Ahoj')
            recorder = audiorecorder(app.Fs, app.nBits, app.nChannels);
            while true
                recordBlocking(recorder, app.recDur);
                sig = getaudiodata(recorder);
                q = app.Text.Value;
                app.Text.Value = strcat(q, '3');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            p = gcp();
            F = parfeval(p, @audioProcessor, 0, app);
            disp(F.Diary)
        end

        % Button pushed function: B0
        function B0ButtonPushed(app, event)
            q = app.Text.Value;
            app.Text.Value = strcat(q, '0');
        end

        % Button pushed function: B_Dot
        function B_DotButtonPushed(app, event)
            q = app.Text.Value;
            app.Text.Value = strcat(q, '.');
        end

        % Button pushed function: B3
        function B3ButtonPushed(app, event)
            q = app.Text.Value;
            app.Text.Value = strcat(q, '3');
        end

        % Button pushed function: B2
        function B2ButtonPushed(app, event)
            q = app.Text.Value;
            app.Text.Value = strcat(q, '2');
        end

        % Button pushed function: B1
        function B1ButtonPushed(app, event)
            q = app.Text.Value;
            app.Text.Value = strcat(q, '1');
        end

        % Button pushed function: B_Multiply
        function B_MultiplyButtonPushed(app, event)
            q = app.Text.Value;
            app.Text.Value = strcat(q, '*');
        end

        % Button pushed function: B6
        function B6ButtonPushed(app, event)
            q = app.Text.Value;
            app.Text.Value = strcat(q, '6');
        end

        % Button pushed function: B5
        function B5ButtonPushed(app, event)
            q = app.Text.Value;
            app.Text.Value = strcat(q, '5');
        end

        % Button pushed function: B4
        function B4ButtonPushed(app, event)
            q = app.Text.Value;
            app.Text.Value = strcat(q, '4');
        end

        % Button pushed function: B_Divide
        function B_DivideButtonPushed(app, event)
            q = app.Text.Value;
            app.Text.Value = strcat(q, '/');
        end

        % Button pushed function: B9
        function B9ButtonPushed(app, event)
            q = app.Text.Value;
            app.Text.Value = strcat(q, '9');
        end

        % Button pushed function: B8
        function B8ButtonPushed(app, event)
            q = app.Text.Value;
            app.Text.Value = strcat(q, '8');
        end

        % Button pushed function: B7
        function B7ButtonPushed(app, event)
            q = app.Text.Value;
            app.Text.Value = strcat(q, '7');
        end

        % Button pushed function: B_Plus
        function B_PlusButtonPushed(app, event)
            q = app.Text.Value;
            app.Text.Value = strcat(q, '+');
        end

        % Button pushed function: B_Minus
        function B_MinusButtonPushed(app, event)
            q = app.Text.Value;
            app.Text.Value = strcat(q, '-');
        end

        % Button pushed function: B_Clear
        function B_ClearButtonPushed(app, event)
            q = app.Text.Value;
            app.Text.Value = '';
        end

        % Button pushed function: B_Back
        function B_BackButtonPushed(app, event)
            q = app.Text.Value;
            app.Text.Value = q(1:end-1);
        end

        % Button pushed function: B_Equal
        function B_EqualButtonPushed(app, event)
            try
                a = eval(app.Text.Value);
                app.Text.Value = string(a);
            catch E
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Gauge = uigauge(app.UIFigure, 'linear');
            app.Gauge.Limits = [0 2];
            app.Gauge.MajorTicks = [0 1 2];
            app.Text = uieditfield(app.UIFigure, 'text');
            app.Text.Editable = 'off';
            app.B_Back = uibutton(app.UIFigure, 'push');
            app.B_Back.ButtonPushedFcn = createCallbackFcn(app, @B_BackButtonPushed, true);
            app.B_Back.Text = '←';
            app.B_Clear = uibutton(app.UIFigure, 'push');
            app.B_Clear.ButtonPushedFcn = createCallbackFcn(app, @B_ClearButtonPushed, true);
            app.B_Clear.Text = 'CE';
            app.B_Minus = uibutton(app.UIFigure, 'push');
            app.B_Minus.ButtonPushedFcn = createCallbackFcn(app, @B_MinusButtonPushed, true);
            app.B_Minus.Text = '-';
            app.B_Plus = uibutton(app.UIFigure, 'push');
            app.B_Plus.ButtonPushedFcn = createCallbackFcn(app, @B_PlusButtonPushed, true);
            app.B_Plus.Text = '+';
            app.B7 = uibutton(app.UIFigure, 'push');
            app.B7.ButtonPushedFcn = createCallbackFcn(app, @B7ButtonPushed, true);
            app.B7.Text = '7';
            app.B8 = uibutton(app.UIFigure, 'push');
            app.B8.ButtonPushedFcn = createCallbackFcn(app, @B8ButtonPushed, true);
            app.B8.Text = '8';
            app.B9 = uibutton(app.UIFigure, 'push');
            app.B9.ButtonPushedFcn = createCallbackFcn(app, @B9ButtonPushed, true);
            app.B9.Text = '9';
            app.B_Divide = uibutton(app.UIFigure, 'push');
            app.B_Divide.ButtonPushedFcn = createCallbackFcn(app, @B_DivideButtonPushed, true);
            app.B_Divide.Text = '/';
            app.B4 = uibutton(app.UIFigure, 'push');
            app.B4.ButtonPushedFcn = createCallbackFcn(app, @B4ButtonPushed, true);
            app.B4.Text = '4';
            app.B5 = uibutton(app.UIFigure, 'push');
            app.B5.ButtonPushedFcn = createCallbackFcn(app, @B5ButtonPushed, true);
            app.B5.Text = '5';
            app.B6 = uibutton(app.UIFigure, 'push');
            app.B6.ButtonPushedFcn = createCallbackFcn(app, @B6ButtonPushed, true);
            app.B6.Text = '6';
            app.B_Multiply = uibutton(app.UIFigure, 'push');
            app.B_Multiply.ButtonPushedFcn = createCallbackFcn(app, @B_MultiplyButtonPushed, true);
            app.B_Multiply.Text = '*';
            app.B1 = uibutton(app.UIFigure, 'push');
            app.B1.ButtonPushedFcn = createCallbackFcn(app, @B1ButtonPushed, true);
            app.B1.Text = '1';
            app.B2 = uibutton(app.UIFigure, 'push');
            app.B2.ButtonPushedFcn = createCallbackFcn(app, @B2ButtonPushed, true);
            app.B2.Text = '2';
            app.B3 = uibutton(app.UIFigure, 'push');
            app.B3.ButtonPushedFcn = createCallbackFcn(app, @B3ButtonPushed, true);
            app.B3.Text = '3';
            app.B_Equal = uibutton(app.UIFigure, 'push');
            app.B_Equal.ButtonPushedFcn = createCallbackFcn(app, @B_EqualButtonPushed, true);
            app.B_Equal.Text = '=';
            app.B0 = uibutton(app.UIFigure, 'push');
            app.B0.ButtonPushedFcn = createCallbackFcn(app, @B0ButtonPushed, true);
            app.B0.Text = '0';
            app.B_Dot = uibutton(app.UIFigure, 'push');
            app.B_Dot.ButtonPushedFcn = createCallbackFcn(app, @B_DotButtonPushed, true);
            app.B_Dot.Text = '.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end